classdef AHPSRec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AHPSRecUIFigure         matlab.ui.Figure
        ArduinoMenu             matlab.ui.container.Menu
        ConnectMenu             matlab.ui.container.Menu
        DisconnectMenu          matlab.ui.container.Menu
        InfoMenu                matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        SettingsPanel           matlab.ui.container.Panel
        TimesEditField          matlab.ui.control.NumericEditField
        TimesEditFieldLabel     matlab.ui.control.Label
        PPRLabel                matlab.ui.control.Label
        RecordingPanel          matlab.ui.container.Panel
        StopButton              matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        AxisPanel               matlab.ui.container.Panel
        AxisYmaxEditField       matlab.ui.control.NumericEditField
        mmHGmaxLabel            matlab.ui.control.Label
        AxisYminEditField       matlab.ui.control.NumericEditField
        mmHGminLabel            matlab.ui.control.Label
        AxisXmaxEditField       matlab.ui.control.NumericEditField
        TimemaxLabel            matlab.ui.control.Label
        AxisXminEditField       matlab.ui.control.NumericEditField
        TimeminLabel            matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        Image                   matlab.ui.control.Image
        StatusConnectionPanel   matlab.ui.container.Panel
        ConnectionLabel         matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        Panel_2                 matlab.ui.container.Panel
        TimeEditField           matlab.ui.control.EditField
        TimeEditFieldLabel      matlab.ui.control.Label
        mmHGLabel               matlab.ui.control.Label
        PressureEditField       matlab.ui.control.NumericEditField
        PressureEditFieldLabel  matlab.ui.control.Label
        Gauge                   matlab.ui.control.Gauge
        mmHgLabel               matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Plot_UIAxes             matlab.ui.control.UIAxes
        ContextMenu             matlab.ui.container.ContextMenu
        ReconnectMenu           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        AxisXmin % Description
        AxisXmax % Description
        AxisYmin % Description
        AxisYmax % Description
        connected_arduino % Description
        ArduinoInfo % Description
        measBackground
        OutMin
        OutMax
        PressureMin
        PressureMax
        I2Cdev
        MeasStart
        h
        delay
    end
    
    methods (Access = private)
        
        function connect_to_Arduino(app)
            
            try
                app.connected_arduino = arduino();
            catch
                warndlg(["Connecting to Arduino failed";"Try to clear any existing connections in the workspace"])
            end
            
            if class(app.connected_arduino) == "arduino"
                app.Lamp.Color = [0 1 0];
                app.ConnectionLabel.Text = "Connected";
                
            end
            
            writeDigitalPin( app.connected_arduino, "D13", 1);
            addrs = scanI2CBus( app.connected_arduino);
            app.I2Cdev =  device( app.connected_arduino,'I2CAddress',addrs{1});
            write(app.I2Cdev, 0x0, 'uint8');
            pressureMeasurement(app);
        end
        
        function  disconnect_Arduino(app)
            
            try
                for i = 2:13
                    configurePin(app.connected_arduino,append("D",num2str(i)),"Unset");
                end
                
            catch
                warning("Couldn't unset pins succesfully");
            end
            
            
            app.connected_arduino = double.empty;
            if class(app.connected_arduino) == "double"
                app.Lamp.Color = [0.8 0.8 0.8];
                app.ConnectionLabel.Text = "Not connected";
            end
        end
        
        
        function pressureMeasurement(app)
            
       if(isempty(app.connected_arduino) == 0) 


            while(app.measBackground == true)
                data = read(app.I2Cdev, 2, 'uint8');
                
                Output = 256 * data(1) + data(2);
                Pressure = round((((Output - app.OutMin) * (app.PressureMax - app.PressureMin)) / (app.OutMax - app.OutMin)) + app.PressureMin,2);
                app.Gauge.Value = Pressure;
                app.PressureEditField.Value = Pressure;
                pause(app.delay);
            end
            
            if(app.measBackground == false)
                app.h = animatedline(app.Plot_UIAxes);
                startTime = datetime("now");
                while(app.MeasStart == true)
                    data = read(app.I2Cdev, 2, 'uint8');
                    
                    Output = 256 * data(1) + data(2);
                    time = seconds(datetime("now") - startTime) ;
                    formatted_time = datestr(time/86400, 'HH:MM:SS');
                    app.TimeEditField.Value = formatted_time;
                  Pressure = round((((Output - app.OutMin) * (app.PressureMax - app.PressureMin)) / (app.OutMax - app.OutMin)) + app.PressureMin,2);
                    addpoints(app.h, time, Pressure);
                    app.Gauge.Value = Pressure;
                    app.PressureEditField.Value = Pressure;
                    drawnow
                    pause(app.delay);
                end
            end
            
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AxisXmin = 0;
            app.AxisXmax = 3000;
            app.AxisYmin = 0;
            app.AxisYmax = 300;
            app.AxisXminEditField.Value = app.AxisXmin;
            app.AxisXmaxEditField.Value = app.AxisXmax;
            app.AxisYminEditField.Value = app.AxisYmin;
            app.AxisYmaxEditField.Value = app.AxisYmax;
            app.Plot_UIAxes.XLim = [app.AxisXmin app.AxisXmax];
            app.Plot_UIAxes.YLim = [app.AxisYmin app.AxisYmax];
            app.measBackground = true;
            app.OutMin = 1638;
            app.OutMax = 14746;
            app.PressureMin = 0;
            app.PressureMax = 258.575;
            app.MeasStart = false;
            app.delay = app.TimesEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AHPSRecUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {514, 514};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: AxisXminEditField
        function AxisXminEditFieldValueChanged(app, event)
            app.AxisXmin = app.AxisXminEditField.Value;
            app.Plot_UIAxes.XLim = [app.AxisXmin app.AxisXmax];
        end

        % Value changed function: AxisXmaxEditField
        function AxisXmaxEditFieldValueChanged(app, event)
            app.AxisXmax = app.AxisXmaxEditField.Value;
            app.Plot_UIAxes.XLim = [app.AxisXmin app.AxisXmax];
        end

        % Value changed function: AxisYminEditField
        function AxisYminEditFieldValueChanged(app, event)
            app.AxisYmin = app.AxisYminEditField.Value;
            app.Plot_UIAxes.YLim = [app.AxisYmin app.AxisYmax];
        end

        % Value changed function: AxisYmaxEditField
        function AxisYmaxEditFieldValueChanged(app, event)
            app.AxisYmax = app.AxisYmaxEditField.Value;
            app.Plot_UIAxes.YLim = [app.AxisYmin app.AxisYmax];
        end

        % Menu selected function: ConnectMenu
        function ConnectMenuSelected(app, event)
            connect_to_Arduino(app)
        end

        % Menu selected function: ReconnectMenu
        function ReconnectMenuSelected(app, event)
            disconnect_Arduino(app)
            connect_to_Arduino(app)
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            app.ArduinoInfo = AHPSRec_ArduinoInfo(app.connected_arduino);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.Plot_UIAxes);
            app.measBackground = false;
            app.MeasStart = true;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.MeasStart = false;
            app.measBackground = true;
            pressureMeasurement(app)

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.MeasStart = false;
            [data_x, data_y] = getpoints(app.h);
            data = [data_x' data_y'];
            data = array2table(data, 'VariableNames',{'time(s)','p(mmHG)'});
            [file, path] = uiputfile({'*.csv'; '*.tsv'});
            writetable(data, fullfile(path,file))
        end

        % Value changed function: TimesEditField
        function TimesEditFieldValueChanged(app, event)
            value = app.TimesEditField.Value;
            app.delay = value;
        end

        % Menu selected function: DisconnectMenu
        function DisconnectMenuSelected(app, event)
                        disconnect_Arduino(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AHPSRecUIFigure = uifigure('Visible', 'off');
            app.AHPSRecUIFigure.Name = 'AHPSRec';
            app.AHPSRecUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ArduinoMenu = uimenu(app.AHPSRecUIFigure);
            app.ArduinoMenu.Text = 'Arduino';
            app.ConnectMenu = uimenu(app.ArduinoMenu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectMenuSelected, true);
            app.ConnectMenu.Text = 'Connect';
            app.DisconnectMenu = uimenu(app.ArduinoMenu);
            app.DisconnectMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectMenuSelected, true);
            app.DisconnectMenu.Text = 'Disconnect';
            app.InfoMenu = uimenu(app.ArduinoMenu);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.HelpMenu = uimenu(app.AHPSRecUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.AHPSRecUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AxisPanel = uipanel(app.LeftPanel);
            app.AxisPanel.Title = 'Axis';
            app.TimeminLabel = uilabel(app.AxisPanel);
            app.TimeminLabel.Text = 'Time min';
            app.AxisXminEditField = uieditfield(app.AxisPanel, 'numeric');
            app.AxisXminEditField.ValueChangedFcn = createCallbackFcn(app, @AxisXminEditFieldValueChanged, true);
            app.TimemaxLabel = uilabel(app.AxisPanel);
            app.TimemaxLabel.Text = 'Time max';
            app.AxisXmaxEditField = uieditfield(app.AxisPanel, 'numeric');
            app.AxisXmaxEditField.ValueChangedFcn = createCallbackFcn(app, @AxisXmaxEditFieldValueChanged, true);
            app.AxisXmaxEditField.Value = 3000;
            app.mmHGminLabel = uilabel(app.AxisPanel);
            app.mmHGminLabel.Text = 'mmHg min';
            app.AxisYminEditField = uieditfield(app.AxisPanel, 'numeric');
            app.AxisYminEditField.ValueChangedFcn = createCallbackFcn(app, @AxisYminEditFieldValueChanged, true);
            app.mmHGmaxLabel = uilabel(app.AxisPanel);
            app.mmHGmaxLabel.Text = 'mmHg max';
            app.AxisYmaxEditField = uieditfield(app.AxisPanel, 'numeric');
            app.AxisYmaxEditField.ValueChangedFcn = createCallbackFcn(app, @AxisYmaxEditFieldValueChanged, true);
            app.AxisYmaxEditField.Value = 300;
            app.RecordingPanel = uipanel(app.LeftPanel);
            app.RecordingPanel.Title = 'Recording';
            app.StartButton = uibutton(app.RecordingPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.RecordingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StopButton = uibutton(app.RecordingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PPRLabel = uilabel(app.LeftPanel);
            app.PPRLabel.Text = 'PPR';
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.TimesEditFieldLabel = uilabel(app.SettingsPanel);
            app.TimesEditFieldLabel.Text = 'ΔTime (s)';
            app.TimesEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.TimesEditField.Limits = [0 Inf];
            app.TimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimesEditFieldValueChanged, true);
            app.TimesEditField.Value = 0.2;
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.Panel.Title = '    ';
            app.Plot_UIAxes = uiaxes(app.Panel);
            xlabel(app.Plot_UIAxes, 'Time (s)')
            ylabel(app.Plot_UIAxes, 'Pressure (mmHg)')
            app.Plot_UIAxes.XLim = [0 3000];
            app.Plot_UIAxes.YLim = [0 300];
            app.Panel_2 = uipanel(app.RightPanel);
            app.Panel_2.Title = '    ';
            app.mmHgLabel = uilabel(app.Panel_2);
            app.mmHgLabel.Text = 'mmHg';
            app.Gauge = uigauge(app.Panel_2, 'circular');
            app.Gauge.Limits = [0 180];
            app.PressureEditFieldLabel = uilabel(app.Panel_2);
            app.PressureEditFieldLabel.Text = 'Pressure:';
            app.PressureEditField = uieditfield(app.Panel_2, 'numeric');
            app.PressureEditField.ValueDisplayFormat = '%.1f';
            app.PressureEditField.Editable = 'off';
            app.mmHGLabel = uilabel(app.Panel_2);
            app.mmHGLabel.Text = 'mmHG';
            app.TimeEditFieldLabel = uilabel(app.Panel_2);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.Panel_2, 'text');
            app.TimeEditField.Editable = 'off';
            app.TimeEditField.Value = '00:00:00';
            app.StatusConnectionPanel = uipanel(app.RightPanel);
            app.StatusConnectionPanel.Title = 'Status Connection:';
            app.Lamp = uilamp(app.StatusConnectionPanel);
            app.ConnectionLabel = uilabel(app.StatusConnectionPanel);
            app.ConnectionLabel.Text = 'Not Connected';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'mouse-side-view-publicdomain.jpg';
            app.ContextMenu = uicontextmenu(app.AHPSRecUIFigure);
            app.ReconnectMenu = uimenu(app.ContextMenu);
            app.ReconnectMenu.MenuSelectedFcn = createCallbackFcn(app, @ReconnectMenuSelected, true);
            app.ReconnectMenu.Text = 'Reconnect';
            % Assign app.ContextMenu
            app.StatusConnectionPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.AHPSRecUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AHPSRec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AHPSRecUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AHPSRecUIFigure)
        end
    end
end