classdef AHPSRec_ArduinoInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AHPSRec_ArduinoInfoUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        BoardLabel                   matlab.ui.control.Label
        PortLabel                    matlab.ui.control.Label
        PWMPinsLabel                 matlab.ui.control.Label
        DigitalPinsLabel             matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        PWMPinsLabel_2               matlab.ui.control.TextArea
        DigitalPinsLabel_2           matlab.ui.control.TextArea
        BoardLabel_2                 matlab.ui.control.TextArea
        PortLabel_2                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        AHPSRec % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, connected_arduino)
            try
            app.BoardLabel_2.Value = connected_arduino.Board;
            app.DigitalPinsLabel_2.Value = connected_arduino.AvailableDigitalPins;
            app.PWMPinsLabel_2.Value = connected_arduino.AvailablePWMPins;
            app.PortLabel_2.Value = connected_arduino.Port;
            catch
                warning("No Arduino found.");
            end
        end

        % Callback function: AHPSRec_ArduinoInfoUIFigure, OKButton
        function AHPSRec_ArduinoInfoUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function OKButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AHPSRec_ArduinoInfoUIFigure = uifigure('Visible', 'off');
            app.AHPSRec_ArduinoInfoUIFigure.Name = 'AHPSRec_ArduinoInfo';
            app.AHPSRec_ArduinoInfoUIFigure.CloseRequestFcn = createCallbackFcn(app, @AHPSRec_ArduinoInfoUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.AHPSRec_ArduinoInfoUIFigure);
            app.BoardLabel = uilabel(app.GridLayout);
            app.BoardLabel.Text = 'Board:';
            app.PortLabel = uilabel(app.GridLayout);
            app.PortLabel.Text = 'Port:';
            app.PWMPinsLabel = uilabel(app.GridLayout);
            app.PWMPinsLabel.Text = 'PWM Pins:';
            app.DigitalPinsLabel = uilabel(app.GridLayout);
            app.DigitalPinsLabel.Text = 'Digital Pins:';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @AHPSRec_ArduinoInfoUIFigureCloseRequest, true);
            app.OKButton.Text = 'OK';
            app.PWMPinsLabel_2 = uitextarea(app.GridLayout);
            app.PWMPinsLabel_2.Editable = 'off';
            app.DigitalPinsLabel_2 = uitextarea(app.GridLayout);
            app.DigitalPinsLabel_2.Editable = 'off';
            app.BoardLabel_2 = uitextarea(app.GridLayout);
            app.BoardLabel_2.Editable = 'off';
            app.PortLabel_2 = uitextarea(app.GridLayout);
            app.PortLabel_2.Editable = 'off';
            % Show the figure after all components are created
            app.AHPSRec_ArduinoInfoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AHPSRec_ArduinoInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AHPSRec_ArduinoInfoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AHPSRec_ArduinoInfoUIFigure)
        end
    end
end